classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea                matlab.ui.control.TextArea
        Label                   matlab.ui.control.Label
        EditEvaluationButton    matlab.ui.control.Button
        UploadImageButton_2     matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        UploadImageButton       matlab.ui.control.Button
        ChangeImageButton       matlab.ui.control.Button
        ProceedButton           matlab.ui.control.Button
        ImageProcessed          matlab.ui.control.Image
        ImageRaw                matlab.ui.control.Image
        ImageNoiseReduced       matlab.ui.control.Image
        ImageAfterSegmentation  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChangeImageButton, UploadImageButton, 
        % ...and 1 other component
        function UploadImageButtonPushed(app, event)
            % Configure acceptable graphic extensions
            fileFilters = {'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif)'};
    
            % Open user navigation window
            [filename, pathname] = uigetfile(fileFilters, 'Select an Image');
    
            % Verify a valid file was selected
            if ~isequal(filename,0) && ~isequal(pathname,0)
                % Directly assign the file path to the Image component
                app.Image.ImageSource = fullfile(pathname, filename);
                app.ImageRaw.ImageSource = fullfile(pathname, filename);
                
                % Hides the button
                app.UploadImageButton.Visible = 'off';

                % Show the two new buttons
                app.ChangeImageButton.Visible = 'on';
                app.ProceedButton.Visible = 'on';
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            % Hide Previous Buttons and Images
            app.ProceedButton.Visible = 'off';
            app.ChangeImageButton.Visible = 'off';
            app.Image.Visible = "off";

            % Show Images
            app.ImageProcessed.Visible ="on"
            app.ImageRaw.Visible ="on"
            app.ImageNoiseReduced.Visible ="on"
            app.ImageAfterSegmentation.Visible ="on"
            app.UploadImageButton_2.Visible = "on"
            app.EditEvaluationButton.Visible = "on"
            app.TextArea.Visible = "on"
        end

        % Button pushed function: EditEvaluationButton
        function EditEvaluationButtonPushed(app, event)
            % Show Images
            app.TextArea.Editable = "on"
            app.TextArea.Enable = "on"
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAfterSegmentation = uiimage(app.UIFigure);
            app.ImageAfterSegmentation.Visible = 'off';
            app.ImageNoiseReduced = uiimage(app.UIFigure);
            app.ImageNoiseReduced.Visible = 'off';
            app.ImageRaw = uiimage(app.UIFigure);
            app.ImageRaw.Visible = 'off';
            app.ImageProcessed = uiimage(app.UIFigure);
            app.ImageProcessed.Visible = 'off';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Visible = 'off';
            app.ProceedButton.Text = 'Proceed';
            app.ChangeImageButton = uibutton(app.UIFigure, 'push');
            app.ChangeImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.ChangeImageButton.Visible = 'off';
            app.ChangeImageButton.Text = 'Change Image';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.Image = uiimage(app.UIFigure);
            app.UploadImageButton_2 = uibutton(app.UIFigure, 'push');
            app.UploadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton_2.Visible = 'off';
            app.UploadImageButton_2.Text = 'Upload Image';
            app.EditEvaluationButton = uibutton(app.UIFigure, 'push');
            app.EditEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @EditEvaluationButtonPushed, true);
            app.EditEvaluationButton.Visible = 'off';
            app.EditEvaluationButton.Text = 'Edit Evaluation';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end